classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NoteIKinewillnotrunifXYZisoutofDobotlimitLabel  matlab.ui.control.Label
        GoBackButton         matlab.ui.control.Button
        Theta_5Slider        matlab.ui.control.Slider
        Theta_5SliderLabel   matlab.ui.control.Label
        Theta_4Slider        matlab.ui.control.Slider
        Theta_4SliderLabel   matlab.ui.control.Label
        Theta_3Slider        matlab.ui.control.Slider
        Theta_3SliderLabel   matlab.ui.control.Label
        Theta_1Slider        matlab.ui.control.Slider
        Theta_1SliderLabel   matlab.ui.control.Label
        Theta_2Slider        matlab.ui.control.Slider
        Theta_2SliderLabel   matlab.ui.control.Label
        Z_posEditField       matlab.ui.control.NumericEditField
        Z_posEditFieldLabel  matlab.ui.control.Label
        Y_posEditField       matlab.ui.control.NumericEditField
        Y_posEditFieldLabel  matlab.ui.control.Label
        X_posEditField       matlab.ui.control.NumericEditField
        X_posEditFieldLabel  matlab.ui.control.Label
        IKineButton          matlab.ui.control.Button
        FKineButton          matlab.ui.control.Button
        ExitBackHomeButton   matlab.ui.control.Button
        DOBOTTEACHLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        % place your class properties here
       
        modelDobot 
        qz = [0     0     0     0     0     0];
        qrDobot = [pi/2	pi/4  pi/4	pi/2	0	0];

        % modelThor
        % qrThor = [pi 0 0 0 0 0]

        Theta1
        Theta2
        Theta3
        Theta4
        Theta5
        
        
        X_pos
        Y_pos
        Z_pos


        Q1
        Q2
        Q3
        Q4
        Q5

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
 % Code to run once GUI is loaded successfully
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
                  
            workspace = [-1 1 , -1 1, 0 1]
            app.modelDobot = DobotMagician;
            app.modelDobot.model.base = transl([0,0,0]);
            app.modelDobot.model.animate(app.qrDobot);
              
            drawnow();
            
            % app.modelThor = Thor;
            % app.modelThor.model.base = transl([0.5,0,0]);
            % app.modelThor.model.animate(app.qrThor);
            % drawnow();


            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
 
            
        end

        % Button pushed function: ExitBackHomeButton
        function ExitBackHomeButtonPushed(app, event)
            app1
            %app.UIFigure.Visible = 'off'
            app.delete
        end

        % Button pushed function: FKineButton
        function FKineButtonPushed(app, event)
            Th_1 = deg2rad(app.Theta1) %app.Theta_1EditFieldValueChanged.Value
            Th_2 = deg2rad(app.Theta2) % str2double(app.Theta_2EditFieldValueChanged(value2))*pi/180
            Th_3 = deg2rad(app.Theta3) % str2double(app.Theta_3EditFieldValueChanged(value3))*pi/180
            Th_4 = deg2rad(app.Theta4) % str2double(app.Theta_4EditFieldValueChanged(value4))*pi/180
            Th_5 = deg2rad(app.Theta5)

            app.modelDobot.model.animate([Th_1 Th_2 Th_3 Th_4 Th_5]);
            drawnow();

            T = app.modelDobot.model.fkine([Th_1 Th_2 Th_3 Th_4 Th_5]).t';

           T_X = T(1,1)
           T_Y = T(1,2);
           T_Z = T(1,3);

           app.X_posEditField.Value= T_X;
           app.Y_posEditField.Value= T_Y;
           app.Z_posEditField.Value= T_Z;


          app.Q1 = Th_1 
          app.Q2 = Th_2
          app.Q3 = Th_3
          app.Q4 = Th_4
          app.Q5 = Th_5


        end

        % Value changed function: X_posEditField
        function X_posEditFieldValueChanged(app, event)
            valueX= app.X_posEditField.Value;
            app.X_pos = valueX
        end

        % Value changed function: Y_posEditField
        function Y_posEditFieldValueChanged(app, event)
            valueY = app.Y_posEditField.Value;
            app.Y_pos = valueY  
        end

        % Value changed function: Z_posEditField
        function Z_posEditFieldValueChanged(app, event)
            valueZ = app.Z_posEditField.Value;
            app.Z_pos= valueZ;
        end

        % Button pushed function: IKineButton
        function IKineButtonPushed(app, event)
            X = app.X_pos
            Y = app.Y_pos
            Z = app.Z_pos

            %q0 = zeros(1,5)

            Q0 = [app.Q1 app.Q2 app.Q3 app.Q4 app.Q5]

            %Q0 = [0 0 0 0 0]

            %Q0 = [deg2rad(-10) deg2rad(40) deg2rad(60) deg2rad(20) deg2rad(10)]

            Q = app.modelDobot.model.ikine(transl([X,Y,Z])*rpy2tr(0,pi/2,0),'q0',Q0, 'mask', [1,1,1,0,0,0]);
            
            app.modelDobot.model.animate(Q);

            drawnow();


         %app.modelDobot.model.animate([Th_1 Th_2 Th_3 Th_4 Th_5]);
            %drawnow();
        end

        % Value changed function: Theta_2Slider
        function Theta_2SliderValueChanged(app, event)
            value2 = app.Theta_2Slider.Value;
            app.Theta2 = value2
        end

        % Value changed function: Theta_1Slider
        function Theta_1SliderValueChanged(app, event)
            value1 = app.Theta_1Slider.Value;
            app.Theta1 = value1
        end

        % Value changed function: Theta_3Slider
        function Theta_3SliderValueChanged(app, event)
            value3 = app.Theta_3Slider.Value;
            app.Theta3= value3
        end

        % Value changed function: Theta_4Slider
        function Theta_4SliderValueChanged(app, event)
            value4 = app.Theta_4Slider.Value;
            app.Theta4 = value4
        end

        % Value changed function: Theta_5Slider
        function Theta_5SliderValueChanged(app, event)
            value5 = app.Theta_5Slider.Value;
            app.Theta5 = value5
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            app2
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DOBOTTEACHLabel = uilabel(app.UIFigure);
            app.DOBOTTEACHLabel.Text = 'DOBOT TEACH';
            app.ExitBackHomeButton = uibutton(app.UIFigure, 'push');
            app.ExitBackHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ExitBackHomeButtonPushed, true);
            app.ExitBackHomeButton.Text = 'Exit Back Home';
            app.FKineButton = uibutton(app.UIFigure, 'push');
            app.FKineButton.ButtonPushedFcn = createCallbackFcn(app, @FKineButtonPushed, true);
            app.FKineButton.Tag = 'btn_Forward';
            app.FKineButton.Text = 'FKine';
            app.IKineButton = uibutton(app.UIFigure, 'push');
            app.IKineButton.ButtonPushedFcn = createCallbackFcn(app, @IKineButtonPushed, true);
            app.IKineButton.Tag = 'btn_Inverse';
            app.IKineButton.Text = 'IKine';
            app.X_posEditFieldLabel = uilabel(app.UIFigure);
            app.X_posEditFieldLabel.Text = 'X _pos';
            app.X_posEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_posEditField.ValueChangedFcn = createCallbackFcn(app, @X_posEditFieldValueChanged, true);
            app.X_posEditField.Tag = 'Pos_x';
            app.Y_posEditFieldLabel = uilabel(app.UIFigure);
            app.Y_posEditFieldLabel.Text = 'Y _pos';
            app.Y_posEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_posEditField.ValueChangedFcn = createCallbackFcn(app, @Y_posEditFieldValueChanged, true);
            app.Y_posEditField.Tag = 'Pos_y';
            app.Z_posEditFieldLabel = uilabel(app.UIFigure);
            app.Z_posEditFieldLabel.Text = 'Z_pos';
            app.Z_posEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_posEditField.ValueChangedFcn = createCallbackFcn(app, @Z_posEditFieldValueChanged, true);
            app.Z_posEditField.Tag = 'Pos_z';
            app.Theta_2SliderLabel = uilabel(app.UIFigure);
            app.Theta_2SliderLabel.Text = 'Theta_2';
            app.Theta_2Slider = uislider(app.UIFigure);
            app.Theta_2Slider.Limits = [5 85];
            app.Theta_2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta_2SliderValueChanged, true);
            app.Theta_2Slider.Value = 5;
            app.Theta_1SliderLabel = uilabel(app.UIFigure);
            app.Theta_1SliderLabel.Text = 'Theta_1';
            app.Theta_1Slider = uislider(app.UIFigure);
            app.Theta_1Slider.Limits = [-135 135];
            app.Theta_1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta_1SliderValueChanged, true);
            app.Theta_3SliderLabel = uilabel(app.UIFigure);
            app.Theta_3SliderLabel.Text = 'Theta_3';
            app.Theta_3Slider = uislider(app.UIFigure);
            app.Theta_3Slider.Limits = [-5 85];
            app.Theta_3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta_3SliderValueChanged, true);
            app.Theta_4SliderLabel = uilabel(app.UIFigure);
            app.Theta_4SliderLabel.Text = 'Theta_4';
            app.Theta_4Slider = uislider(app.UIFigure);
            app.Theta_4Slider.Limits = [0 180];
            app.Theta_4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta_4SliderValueChanged, true);
            app.Theta_5SliderLabel = uilabel(app.UIFigure);
            app.Theta_5SliderLabel.Text = 'Theta_5';
            app.Theta_5Slider = uislider(app.UIFigure);
            app.Theta_5Slider.Limits = [-85 85];
            app.Theta_5Slider.ValueChangedFcn = createCallbackFcn(app, @Theta_5SliderValueChanged, true);
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.NoteIKinewillnotrunifXYZisoutofDobotlimitLabel = uilabel(app.UIFigure);
            app.NoteIKinewillnotrunifXYZisoutofDobotlimitLabel.Text = 'Note: IKine will not run if XYZ is out of Dobot limit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end